classdef Copy_6_of_EZScope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        PauseButton             matlab.ui.control.Button
        TriggerModeButtonGroup  matlab.ui.container.ButtonGroup
        Knob2                   matlab.ui.control.Knob
        SingleTriggerButton     matlab.ui.control.Button
        FallingEdgeButton       matlab.ui.control.RadioButton
        RisingEdgeButton        matlab.ui.control.RadioButton
        IButton                 matlab.ui.control.Button
        RunButton               matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        comport % Object for serial communication
        clockfreq = 16e6 % Clock frequency
        npoints = 400; % Number of data points matching C code
        % UPDATED: Set to 49 based on your latest measurement
        daccycles = 49;
        stop_req = false; %Flag to raise to stop continuous loop 
    end
    
% Callbacks that handle component events
    methods (Access = private)
        % Code that executes after component creation
        function startupFcn(app)
            % Configure Serial Port (Adjust COM port as needed)
            % Check Device Manager for the correct port (e.g., COM3, COM4)
            try
                % CHANGE "COM3" TO YOUR ACTUAL PORT (e.g., "COM4")
                app.comport = serialport("COM4", 115200); 
                configureTerminator(app.comport, "LF");n

                % Set initial button states
                app.PauseButton.Enable = 'off';
            catch
                uialert(app.UIFigure, "Could not connect to COM port. Check Device Manager.", "Connection Error");
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % 1. CONNECT ONLY IF NOT ALREADY CONNECTED
            % Check if app.comport is empty or if the connection is invalid (closed)
            if isempty(app.comport) || ~isvalid(app.comport)
                try
                    app.comport = serialport("COM4", 115200); 
                    configureTerminator(app.comport, "LF");
                catch
                    uialert(app.UIFigure, "Could not open COM4. Check connection.", "Error");
                    return;
                end
            end

            % 2. Update UI States
            app.RunButton.Enable = 'off';
            app.SingleTriggerButton.Enable = 'off';
            app.PauseButton.Enable = 'on';
            app.IButton.Enable = 'on';
            
            % 3. Reset Stop Flag
            app.stop_req = false;
            
            % 4. Time Vector Calculation (Microseconds)
            % Time per sample = Cycles / Frequency
            dt_seconds = app.daccycles / app.clockfreq; 
            dt_micros = dt_seconds * 1e6; % Convert to microseconds
            
            % Total time in microseconds
            total_time_us = app.npoints * dt_micros;
            
            % Create time vector (0 to Total Time)
            full_time_vec = linspace(0, total_time_us, app.npoints);
            
            while ~app.stop_req
                % 2. Send Trigger Character to MSP430
                try
                    write(app.comport, 'U', "char"); 
                    
                    % 3. Read Data (Blocking read of 400 bytes)
                    raw_data = read(app.comport, app.npoints, "uint8");

                    % 4. Convert to Voltage
                    % MSP430 C code shifts right by 2, so 0-255 maps to 0-3.3V roughly
                    % V = (Data / 255) * 3.3V (Approx Vcc)
                    voltage_data = (double(raw_data) ./ 255) * 3.3;
                    
                    % 5. PLOT vs TIME
                    % Use the time vector as the X-axis
                    plot(app.UIAxes, full_time_vec, voltage_data, '-y', 'LineWidth', 1.5);
                    
                    % Update Axis Limits (X is now in Microseconds)
                    ylim(app.UIAxes, [0 3.5]); 
                    xlim(app.UIAxes, [0 total_time_us]); % Lock to 0 - 1225us
                    grid(app.UIAxes, 'on');
                    % Force MATLAB to update the UI and check for clicks
                        drawnow; 
                    
                catch
                    app.stop_req = true; % Stop if serial error occurs
                end
            end


            % 6. Reset UI States when loop finishes
            app.RunButton.Enable = 'on';
            app.SingleTriggerButton.Enable = 'on';
            app.PauseButton.Enable = 'off';
            app.IButton.Enable = 'on';
        end

        % Button pushed function: IButton
        function IButtonPushed(app, event)
        app.stop_req = true; % Ensure loop stops
            pause(0.1); % Give loop time to exit
            if ~isempty(app.comport) && isvalid(app.comport)
                delete(app.comport);
            end
            delete(app.UIFigure);
        end

        % Button pushed function: SingleTriggerButton
        function SingleTriggerButtonPushed(app, event)
            % 1. CONNECT ONLY IF NOT ALREADY CONNECTED
            % Check if app.comport is empty or if the connection is invalid (closed)
            if isempty(app.comport) || ~isvalid(app.comport)
                try
                    app.comport = serialport("COM4", 115200); 
                    configureTerminator(app.comport, "LF");
                catch
                    uialert(app.UIFigure, "Could not open COM4. Check connection.", "Error");
                    return;
                end
            end
            
            % 2. Send Trigger Character to MSP430
            try
                write(app.comport, 'U', "char"); 
                
                % 3. Read Data (Blocking read of 400 bytes)
                raw_data = read(app.comport, app.npoints, "uint8");
            catch
                uialert(app.UIFigure, "Failed to read data from MSP430.", "Read Error");
                return;
            end
            % 4. Convert to Voltage
            % MSP430 C code shifts right by 2, so 0-255 maps to 0-3.3V roughly
            % V = (Data / 255) * 3.3V (Approx Vcc)
            voltage_data = (double(raw_data) ./ 255) * 3.3;
            
            % 5. Software Triggering logic
            % Get selected mode from the Button Group 
            trigger_mode = app.TriggerModeButtonGroup.SelectedObject.Text; 
            % Read from the Edit Field 
            trigger_level = app.Knob2.Value;
            trigger_index = 1;

            % Search for the trigger point
            for i = 1:(app.npoints - 1)
                val_index = voltage_data(i);
                val_next = voltage_data(i+1);
                
                if strcmp(trigger_mode, 'Rising Edge')
                    % Rising: Current < Level AND Next >= Level
                    if (val_index < trigger_level) && (val_next >= trigger_level)
                        trigger_index = i;
                        break;
                    end
                elseif strcmp(trigger_mode, 'Falling Edge')
                    % Falling: Current > Level AND Next <= Level
                    if (val_index > trigger_level) && (val_next <= trigger_level)
                        trigger_index = i;
                        break;
                    end
                end
            end
            
            % 5. Slice Data
            sliced_data = voltage_data(trigger_index:end);
            
            % 6. Create Sliced Time Vector (Microseconds)
            % Time step in microseconds
            dt_us = (app.daccycles / app.clockfreq) * 1e6;
            
            % Create vector from 0 to length of sliced data
            sliced_time = (0:(length(sliced_data)-1)) * dt_us;

            % 7. Plot
            plot(app.UIAxes, sliced_time, sliced_data, '-y', 'LineWidth', 1.5);
            
            % Limits
            ylim(app.UIAxes, [0 3.5]); 
            % Set X limit to the full window size so the scale doesn't jump
            total_window_us = app.npoints * dt_us;
            xlim(app.UIAxes, [0 total_window_us]);
            grid(app.UIAxes, 'on');

            % Draw the trigger level line for visual reference
            yline(app.UIAxes, trigger_level, 'w', 'Trigger Level');
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.stop_req = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '*EZSCOPE EC3BB3')
            xlabel(app.UIAxes, 'Time [\mus]')
            ylabel(app.UIAxes, 'Volts [V]')
            zlabel(app.UIAxes, 'Z')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.IButton = uibutton(app.UIFigure, 'push');
            app.IButton.ButtonPushedFcn = createCallbackFcn(app, @IButtonPushed, true);
            app.IButton.Text = '(I)';
            app.TriggerModeButtonGroup = uibuttongroup(app.UIFigure);
            app.TriggerModeButtonGroup.Title = 'Trigger Mode';
            app.RisingEdgeButton = uiradiobutton(app.TriggerModeButtonGroup);
            app.RisingEdgeButton.Text = 'Rising Edge';
            app.RisingEdgeButton.Value = true;
            app.FallingEdgeButton = uiradiobutton(app.TriggerModeButtonGroup);
            app.FallingEdgeButton.Text = 'Falling Edge';
            app.SingleTriggerButton = uibutton(app.TriggerModeButtonGroup, 'push');
            app.SingleTriggerButton.ButtonPushedFcn = createCallbackFcn(app, @SingleTriggerButtonPushed, true);
            app.SingleTriggerButton.Text = 'Single Trigger';
            app.Knob2 = uiknob(app.TriggerModeButtonGroup, 'continuous');
            app.Knob2.Limits = [1 3];
            app.Knob2.Value = 1.5;
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_6_of_EZScope

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end